classdef Lamba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lambda            matlab.ui.Figure
        Button_5          matlab.ui.control.Button
        RsolutionSpinner  matlab.ui.control.Spinner
        RsolutionLabel    matlab.ui.control.Label
        OminLabel         matlab.ui.control.Label
        AminLabel         matlab.ui.control.Label
        OmaxLabel         matlab.ui.control.Label
        AmaxLabel         matlab.ui.control.Label
        LmaxLabel         matlab.ui.control.Label
        LminLabel         matlab.ui.control.Label
        Button_4          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        RenderingLamp     matlab.ui.control.Lamp
        RenderingLabel    matlab.ui.control.Label
        RenderButton      matlab.ui.control.Button
        UITable           matlab.ui.control.Table
        UIAxes2           matlab.ui.control.UIAxes
    end

% Credits:
% With the original VB code from Professor Michel Jaboyedoff.
% Porting in Matlab done by Naël Dillenbourg.
% Save logo design by GreenHill from the Noun Project
% Open logo design by Landan Lloyd from the Noun Project
% Image Export logo by Aybige from the Noun Project


    properties (Access = public)
        %LAMBDA SCRIPT
        Xc=40; % Description
        Yc=40;
        RC=35;
        nombre;
        X0;
        Y0;
        delta;
        Lmin=1000000;
        Lmax=0;
        Nbcol=12;
        nbel; %nombre d'inputs
        ratio=1;
        
        poledip;
        Xl;
        Yl;
        Zl;
        
        alphamax=0;
        orientmax=0;
        alphamin=0;
        orientmin=0;

        
        poledir;
        dip;
        lambdavar;
        
        max_x=0;
        max_y=0;
        min_x=100;
        min_y=100;
        
        C; %Matrice image
        grad; %Gradient de valeur
        
        %GUI DATA
        selectedRow=1;
        selectedCol=1;
        lastkey string = '0';
    end

    methods (Access = private)
        %Call on update%
        function updateplot(app)
            %Notify user something is going on
            set(app.lambda, 'Pointer','watch');
            app.RenderingLamp.Enable='on'; %Make the lamp go red
            app.RenderingLabel.Text='Rendering...';
            drawnow; %refresh to show lamp
            
            resetAxes(app,app.UIAxes2); % Delete plot and set prop 
       
            % Plot modified data and print label
            printcan(app)
            showLabel(app)
            %Notify user it's done
            app.RenderingLamp.Enable='off';
            app.RenderingLabel.Text='No activity';
            set(app.lambda, 'Pointer','arrow');
        end

        %Value initialization
        function init(app)
            %Set résolution
            app.nombre=app.RsolutionSpinner.Value;
            app.Xc=app.nombre;
            app.Yc=app.nombre;
            app.RC=app.nombre-5;
            app.ratio=cast(app.nombre/40,'int32'); 
            %changer la méthode du ratio vers une méthode de vrai hauteur
            t = app.UITable.DisplayData;
            if size(t,1)~=0
                %create table
                app.nbel=min([size(t.Var2,1) size(t.Var3,1) ...
                    size(t.Var4,1)]);
                %Create zeros arrays
                tmp=zeros(app.nbel,1);
                app.Xl=tmp;
                app.Yl=tmp;
                app.Zl=tmp;
                app.poledip=tmp;
                app.C=-ones(2*app.RC+2,2*app.RC+2); % 2 pixel bigger 
                % for outside boxes
                app.grad=ones(2*cast(app.RC/app.Nbcol,'int32')*app.Nbcol+1, ...
                    5*app.ratio+1);
                %Assign value from xls to prog
                app.poledir=t.Var2;
                app.dip=t.Var3;
                app.lambdavar=t.Var4;
            end
            %Assign other values
            app.X0=app.Xc-app.RC;
            app.Y0=app.Yc-app.RC;
            app.delta=2*app.RC/app.nombre;
            disableDefaultInteractivity(app.UIAxes2);
        end
        
        function resetAxes(app,plot)
            cla(plot,'reset') ;
            %Reset value of a plot and properties
            plot.Toolbar.Visible = 'off';
            plot.YDir = 'reverse';
            plot.XAxisLocation = 'top';
            plot.XColor = 'none';
            plot.XTick = [];
            plot.YColor = 'none';
            plot.YTick = [];
            plot.ZTick = [];
            plot.ClippingStyle = 'rectangle';
            plot.TickDir = 'in';
            plot.Color = 'none';
            plot.AmbientLightColor = 'none';
            hold(plot,'on')
            axis(plot,'equal')
            disableDefaultInteractivity(plot);
        end
        
        function showLabel(app)
            %Set label data based on calculated values
            app.LminLabel.Text = "Lmin = "+sprintf('%.3f', ...
                app.Lmin);
            app.LmaxLabel.Text = "Lmax = "+sprintf('%.3f', ...
                app.Lmax);
            app.AmaxLabel.Text = "Amax = "+sprintf('%.3f', ...
                app.alphamax);
            app.AminLabel.Text = "Amin = "+sprintf('%.3f', ...
                app.alphamin);
            app.OmaxLabel.Text = "Omax = "+sprintf('%.3f', ...
                app.orientmax);
            app.OminLabel.Text = "Omin = "+sprintf('%.3f', ...
                app.orientmin);
        end
        
        function total = calcul(app,X,Y,Z)
            total = 0; 
            for i=1:app.nbel
                if app.lambdavar(i)>0
                    proj=app.lambdavar(i)*(X*app.Xl(i)+Y*app.Yl(i)+ ...
                        Z*app.Zl(i));
                    total=total+abs(proj);
                end
            end
        end

        
        function direc = dirinv(app,X,Y)
            direc=0;
            if Y==0
                Y=0.000001;
            end
            direc=rad2deg(atan(X/Y));
            if (X>=0 && Y<0) || (X<0 && Y<=0)
                direc=direc+180;
            end
            if (X<=0 && Y>0)
                direc=direc+360;
            end
        end
        
        function invschmitvar = invschmit(app,rad)
            invschmitvar=0;
            sinus=rad/(app.RC*sqrt(2));
            arg=atan(sinus/(sqrt(1-sinus^2)));
            alpha=(pi/2)-2*arg;
            if arg < 0
                alpha = pi/2;
            end
            invschmitvar=rad2deg(alpha);
        end
        
        function lambdatoxyz(app)
            trans=0;
            penda=0;
            direc=0;  
            for i=1:app.nbel
                if app.lambdavar(i)>0 
                    trans=app.dip(i);
                    app.poledip(i)=pendtopole(app,trans);
                    penda=app.poledip(i);
                    direc=app.poledir(i);
                    app.Xl(i)=projX(app,penda, direc);
                    app.Yl(i)=projY(app,penda, direc);
                    app.Zl(i)=projZ(app,penda);
                end
            end
        end
        
        function printcan(app)
            t = app.UITable.DisplayData;
            if size(t,1)~=0
                init(app);
                rmax=0;
                y1=0;
                y2=0;
                x1=0;
                x2=0;
                xa=0;
                ya=0;
                rtest=0;
                alpha=0;
                orientation=0;
                xp=0;
                yp=0;
                zp=0;
                projtot=0;
                
                minmax1(app);
    
                deltalamb=(app.Lmax-app.Lmin)/app.Nbcol;
                Deltacol=255/app.Nbcol;
                rmax=app.RC;
                rgbcol=0;
                for j=0:app.nombre
                    y1=-app.delta/2+j*app.delta;
                    y2=app.delta/2+j*app.delta;
                    for i=0:app.nombre
                        x1=-app.delta/2+i*app.delta;
                        x2=app.delta/2+i*app.delta;
                        xa=app.X0+i*app.delta-app.Xc;
                        ya=app.Yc-app.Y0-j*app.delta;
                        rtest=sqrt(xa^2+ya^2);
                        if rtest<=rmax
                            alpha=invschmit(app,rtest);
                            orientation=dirinv(app,xa,ya);
                            xp=projX(app,alpha,orientation);
                            yp=projY(app,alpha,orientation);
                            zp=projZ(app,alpha);
                            projtot=calcul(app,xp,yp,zp);
                            l=0;
                            while (projtot>app.Lmin+l*deltalamb) &&...
                                    (l<=app.Nbcol)
                                rgbcol=255-l*Deltacol;
                                l=l+1;
                            end
                            %Fill matrix C for main plot
                            fill_pixels(app,[x1 y1 x2-x1 y2-y1],rgbcol); 
                        end
                    end
                end
                %Draw intensity gradient
                %Le programme VB utilise des lignes, moi des carrés via
                %fill_pixels
                for i=0:app.Nbcol
                    rgbcol=255-i*Deltacol;
                    fill_pixels_grad(app,[1 ...
                        2*i*cast(app.RC/app.Nbcol,'int32')+1 ...
                        5*app.ratio ...
                        2*cast(app.RC/app.Nbcol,'int32')+2*app.ratio], ...
                        rgbcol);
                    label = sprintf('%.3f',app.Lmin+i*deltalamb)+"";
                    text(app.UIAxes2, ...
                        app.X0+2*app.RC+7*app.ratio+3, ...
                        2*i*cast(app.RC/app.Nbcol,'int32')+ ...
                        1.5*cast(app.RC/app.Nbcol,'int32'),label);
                end
                B=ones(2*app.RC+2,2*app.RC+2);
                for i=1:2*app.RC+2
                    for j=1:2*app.RC+2
                        if app.C(i,j)==-1
                            B(i,j)=0;
                        end
                    end
                end
                %Print matrix on plot UIAxes2
                image(app.UIAxes2,app.X0,app.Y0,app.C,'CDataMapping', ...
                    "scaled", 'AlphaData',B);
                image(app.UIAxes2,app.X0+2*app.RC+2*app.ratio,app.Y0, ...
                    app.grad, 'CDataMapping',"scaled");
                
            end

            pcanvas(app);
        end
        
        function pcanvas(app)
            f=[app.Xc-3*app.ratio app.Xc+3*app.ratio];
            g=[app.Yc app.Yc];
            p=plot(app.UIAxes2,f,g,'k');
            f=[app.Xc app.Xc];
            g=[app.Yc-3*app.ratio app.Yc+3*app.ratio];
            p=plot(app.UIAxes2,f,g,'k');
            %Draw Nord line
            f=[app.Xc app.Xc];
            g=[app.Yc-app.RC app.Yc-app.RC-5*app.ratio];
            p=plot(app.UIAxes2,f,g,'k');
            %Write "Nord"
            text(app.UIAxes2,app.Xc-3*app.ratio,app.Yc-app.RC-7*app.ratio, ...
                "Nord");
            %Print Circle
            th = 0:pi/50:2*pi;
            xunit=(app.RC)*cos(th)+app.Xc;
            yunit=(app.RC)*sin(th)+app.Yc;
            h=plot(app.UIAxes2,xunit, yunit,'k');
            h.LineWidth=2;
        end
        

        function pendtopole=pendtopole(app,pend)
            pendtopole=90-pend;
        
        end
        
        function projX=projX(app,alphapole, dirpole)
            projX=cos(deg2rad(alphapole))*sin(deg2rad(dirpole));
        end
        
        function projY=projY(app,alphapole,dirpole)
            projY=cos(deg2rad(alphapole))*cos(deg2rad(dirpole));
        end
        
        function projZ=projZ(app,alphapole)
            projZ=sin(deg2rad(alphapole));
        end
        
        function fill_pixels(app,cords,rgbcol)
            for i=0:cords(3)
                for j=0:cords(4)
                    a=cords(1)+i;
                    b=cords(2)+j;
                    if a<0
                        a=0;
                    end
                    if b<0
                        b=0;
                    end
                    a=cast(a+1,'int16');
                    b=cast(b+1,'int16');
                    app.C(b,a)=rgbcol;
                end
            end
        end
        
        function fill_pixels_grad(app,cords,rgbcol)
            for i=0:cords(3)
                for j=0:cords(4)
                    a=cast(abs(cords(1)+i),'int16');
                    b=cast(abs(cords(2)+j),'int16');
                    app.grad(b,a)=rgbcol;
                end
            end
        end
        
        function minmax1(app)
            xp=0;
            yp=0;
            zp=0;
            projtot=0;
            
            app.Lmin=1000000;
            app.Lmax=0;
            lambdatoxyz(app);
            rmax=app.RC;
            
            for j=0:app.nombre
                y1=app.Y0-app.delta/2+j*app.delta;
                y2=app.Y0+app.delta/2+j*app.delta;
                for i=0:app.nombre
                    x1=app.X0-app.delta/2+i*app.delta;
                    x2=app.X0+app.delta/2+i*app.delta;
                    xa=(app.X0+i*app.delta-app.Xc);
                    ya=(app.Yc-app.Y0-j*app.delta);
                    rtest=sqrt(xa^2+ya^2);
                    if rtest <= rmax
                        alpha=invschmit(app,rtest);
                        orientation=dirinv(app,xa,ya);
                        xp=projX(app,alpha, orientation);
                        yp=projY(app,alpha, orientation);
                        zp=projZ(app,alpha);
                        projtot=calcul(app,xp,yp,zp);
                        if projtot>app.Lmax
                           app.Lmax=projtot;
                           app.alphamax=alpha;
                           app.orientmax=orientation;
                        else if projtot<app.Lmin
                           app.Lmin=projtot;
                           app.alphamin=alpha;
                           app.orientmin=orientation;
                        end
                        end
                    end
                end
            end
         end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Plot the data
            set(app.lambda, 'Pointer','watch');
            app.RenderingLamp.Enable='on'; %Make the lamp go red
            app.RenderingLabel.Text='Rendering...';
            drawnow; %refresh to show lamp
            
            resetAxes(app,app.UIAxes2) % Delete plot and set properties 
       
            % Plot modified data
            pcanvas(app)

            app.RenderingLamp.Enable='off';
            app.RenderingLabel.Text='No activity';
            set(app.lambda, 'Pointer','arrow');
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            %Modulo on input data, if this it's slow make sure input
            %is within range or disable modulo, won't affect the results
            t = app.UITable.DisplayData;
            if app.selectedCol==2
                while t.Var2(app.selectedRow)>360
                    t.Var2(app.selectedRow)=t.Var2(app.selectedRow)-360;
                end
                while t.Var2(app.selectedRow)<-360
                    t.Var2(app.selectedRow)=t.Var2(app.selectedRow)+360;
                end
            end
            if app.selectedCol==3
                while t.Var2(app.selectedRow)>90
                    t.Var2(app.selectedRow)=t.Var2(app.selectedRow)-90;
                end
                while t.Var2(app.selectedRow)<-90
                    t.Var2(app.selectedRow)=t.Var2(app.selectedRow)+90;
                end
            end
            set(app.UITable,'Data',t);
            
            % Update the plots when user sorts the columns of the table
            %Can remove % from the next line to auto-refresh plot
            
            %updateplot(app);
        end

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
            app.RenderButton.Enable='off';
            drawnow; %update to show enable off
            updateplot(app); %call update function
            app.RenderButton.Enable='on';
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.selectedRow=event.Indices(1);
            app.selectedCol=event.Indices(2);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %Make sure user knows something is going on
            set(app.lambda, 'Pointer','watch');
            app.RenderingLabel.Text='Add row...';
            app.RenderingLamp.Enable='on';
            drawnow;
            
            %Real work begins
            oldData=get(app.UITable,'Data');
            addedRow=table('Size',[1 4],'VariableTypes',["string","double", ...
                "double","double"]);
            newData=[oldData;addedRow];
            set(app.UITable,'Data',newData);
            %Real work is done
            
            %Notify user that it's done
            set(app.lambda, 'Pointer','arrow');
            app.RenderingLabel.Text='No activity';
            app.RenderingLamp.Enable='off';
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %Make sure user knows something is going on
            app.RenderingLabel.Text='Remove row...';
            app.RenderingLamp.Enable='on';
            set(app.lambda, 'Pointer','watch');
            drawnow; %refresh display
            
            t = app.UITable.DisplayData;
            oldData=get(app.UITable,'Data'); %copy data
            if size(oldData,1)>0
                SumAddedRow=table('Size', ...
                    [size(oldData,1)-1 size(oldData,2)], ...
                    'VariableTypes', ...
                    ["string","double","double","double"]);
                for i=1:app.selectedRow-1 %delete data before
                    addedRow={t.Var1(i),t.Var2(i),t.Var3(i),t.Var4(i)};
                    SumAddedRow(i,:)=addedRow;
                end %Delete data after
                for i=app.selectedRow+1:size(oldData)
                    addedRow={t.Var1(i),t.Var2(i),t.Var3(i),t.Var4(i)};
                    SumAddedRow(i-1,:)=addedRow;
                end
                set(app.UITable,'Data',SumAddedRow);
            end
            
            %Notify user that it's done
            set(app.lambda, 'Pointer','arrow');
            app.RenderingLabel.Text='No activity';
            app.RenderingLamp.Enable='off';
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %Make sure user knows something is going on
            app.RenderingLabel.Text='Waiting on the user...';
            app.RenderingLamp.Enable='on';
            drawnow;
            %Open dialog box: Save
            
            file='0';
            path='0';
            [file,path]=uiputfile('*.xls');
            if file ~=0 %Write file and notify user
                set(app.lambda, 'Pointer','watch');
                app.RenderingLabel.Text='Writing file...';
                writetable(app.UITable.Data,[path file]); 
                set(app.lambda, 'Pointer','arrow');
            end
            
            %User can't break anything now
            app.RenderingLabel.Text='No activity';
            app.RenderingLamp.Enable='off';
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %Make sure user knows something is going on
            app.RenderingLabel.Text='Waiting on the user...';
            app.RenderingLamp.Enable='on';
            
            %Open Dialog box: Import
            file='0';
            path='0';
            [file,path]=uigetfile('*.xls');
            if file ~=0
                %Load data and notify user
                set(app.lambda, 'Pointer','watch');
                app.RenderingLabel.Text='Reading file...';
                t=readtable(fullfile(path,file));
                app.UITable.Data=t;
                set(app.lambda, 'Pointer','arrow');
            end
            
            %User can't break anything now
            app.RenderingLabel.Text='No activity';
            app.RenderingLamp.Enable='off';
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %Make sure user doesn't break anything
            set(app.lambda, 'Pointer','watch');
            app.Button_5.Enable='off';
            app.RenderingLabel.Text='Copying plot...';
            app.RenderingLamp.Enable='on';
            drawnow;
            
            % Create new axis
            figure(size(findobj('Type','figure'),1)+1);
            newAxes = axes;
            resetAxes(app,newAxes);
            
            %Change a few settings for export
            set(gca,'visible','off'); 
            set(gcf,'Color','w'); 
            set(gcf, 'Position',  [0, 0, 800, 800])
            
            %Add information on the plot
            LminTxt = "Lmin = "+sprintf('%.3f',app.Lmin);
            LmaxTxt = "Lmax = "+sprintf('%.3f',app.Lmax);
            AmaxTxt = "Amax = "+sprintf('%.3f',app.alphamax);
            AminTxt = "Amin = "+sprintf('%.3f',app.alphamin);
            OmaxTxt = "Omax = "+sprintf('%.3f',app.orientmax);
            OminTxt = "Omin = "+sprintf('%.3f',app.orientmin);
            ResTxt  = "Resolution = "+sprintf('%.f',app.nombre);
            
            text(newAxes,0,app.ratio+2*app.Xc,0,LminTxt);
            text(newAxes,35*app.ratio,app.ratio+2*app.Xc,0,LmaxTxt);
            text(newAxes,0,app.ratio+2*app.Xc+5*app.ratio,0,AmaxTxt);
            text(newAxes,35*app.ratio,app.ratio+2*app.Xc+5*app.ratio,0,AminTxt);
            text(newAxes,0,app.ratio+2*app.Xc+10*app.ratio,0,OmaxTxt);
            text(newAxes,35*app.ratio,app.ratio+2*app.Xc+10*app.ratio,0,OminTxt);
            text(newAxes,0,app.ratio+2*app.Xc+15*app.ratio,0,ResTxt);
            
            %Add original plot on the exported plot
            copyobj(app.UIAxes2.Children, newAxes);
            fig2Save = ancestor(newAxes, 'figure');
            fig2Save.Visible='off'; % Prevent matlab fig to open
            
            filename=0; %default page to detect if user quit without input
            filepath=0;
            filter = {'*.jpg';'*.png'};
            set(app.lambda,'Pointer','arrow'); % make pointer go normal
            drawnow;
            
            %Dialog box: save as
            [filename, pathname] = uiputfile(filter);
            app.RenderingLabel.Text='Waiting on user...';
            if filename~=0
                app.RenderingLabel.Text='Saving...';
                set(app.lambda, 'Pointer','watch');
                drawnow;
                newfilename = fullfile(pathname, filename);
                saveas(fig2Save, newfilename);
                %export_fig(newfilename); %Other method that can be used
                %but has other problems
                set(app.lambda,'Pointer','arrow');
                drawnow;
            end
            %User can't break anything now
            app.Button_5.Enable='on';
            app.RenderingLabel.Text='No activity...';
            app.RenderingLamp.Enable='off';
        end

        % Key press function: lambda
        function lambdaKeyPressEnter(app, event)
            key = event.Key;
            if app.lastkey=='control'
                switch key
                    case 'return'
                        RenderButtonPushed(app, event); %Render function
                    case 's'
                        Button_3Pushed(app, event); %Save function
                    case 'o'
                        Button_4Pushed(app, event); %Open function
                    case 'e'
                        Button_5Pushed(app, event); %Export plot function
                end
                
            end
            switch key
                case 'add'
                    ButtonPushed(app, event); %+ button function
                case 'subtract'
                    Button_2Pushed(app, event); %- button function
            end
            app.lastkey=key;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.lambda = uifigure('Visible', 'off');
            app.lambda.Name = 'Lambda';
            app.lambda.Icon = 'C:\Users\naeld\OneDrive\Documents\repo\Lambda\Matlab_versions\assets\icon2.png';
            app.lambda.KeyPressFcn = createCallbackFcn(app, @lambdaKeyPressEnter, true);
            app.UIAxes2 = uiaxes(app.lambda);
            app.UIAxes2.YDir = 'reverse';
            app.UIAxes2.XAxisLocation = 'top';
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UITable = uitable(app.lambda);
            app.UITable.ColumnName = {'COMMENTS'; 'AZIMUT'; 'PENDAGE'; 'FREQUENCE'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false false false];
            app.UITable.ColumnEditable = [true true true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.RenderButton = uibutton(app.lambda, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Tooltip = {'Refresh the plot with current input'; ''; 'Shortcut: ctrl+return'};
            app.RenderButton.Text = 'Render';
            app.RenderingLabel = uilabel(app.lambda);
            app.RenderingLabel.Text = 'Rendering...';
            app.RenderingLamp = uilamp(app.lambda);
            app.Button = uibutton(app.lambda, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tooltip = {'Add an input row'; ''; 'Shortcut: add'};
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.lambda, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tooltip = {'Delete the selected input row'; ''; 'Shortcut: substract'};
            app.Button_2.Text = '-';
            app.Button_3 = uibutton(app.lambda, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'assets', 'noun_Save_1152049.png');
            app.Button_3.Tooltip = {'Save the input in .xls'; ''; 'Shortcut: ctrl+s'};
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.lambda, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'assets', 'noun_open_1509601.png');
            app.Button_4.Tooltip = {'Open .xls as an input'; ''; 'Shortcut: ctrl+o'};
            app.Button_4.Text = '';
            app.LminLabel = uilabel(app.lambda);
            app.LminLabel.Text = {'Lmin ='; ''};
            app.LmaxLabel = uilabel(app.lambda);
            app.LmaxLabel.Text = {'Lmax ='; ''};
            app.AmaxLabel = uilabel(app.lambda);
            app.AmaxLabel.Text = {'Amax ='; ''};
            app.OmaxLabel = uilabel(app.lambda);
            app.OmaxLabel.Text = {'Omax ='; ''};
            app.AminLabel = uilabel(app.lambda);
            app.AminLabel.Text = {'Amin ='; ''};
            app.OminLabel = uilabel(app.lambda);
            app.OminLabel.Text = {'Omin ='; ''};
            app.RsolutionLabel = uilabel(app.lambda);
            app.RsolutionLabel.Text = 'Résolution :';
            app.RsolutionSpinner = uispinner(app.lambda);
            app.RsolutionSpinner.Limits = [10 Inf];
            app.RsolutionSpinner.Tooltip = {'Change resolution of plot'};
            app.RsolutionSpinner.Value = 100;
            app.Button_5 = uibutton(app.lambda, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'assets', 'noun_image export_559578.png');
            app.Button_5.Tooltip = {'Save plot as .png or .jpg'; ''; 'Shortcut: ctrl+e'};
            app.Button_5.Text = '';
            % Show the figure after all components are created
            app.lambda.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lamba

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.lambda)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lambda)
        end
    end
end